classdef ui_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectFilterDropDown        matlab.ui.control.DropDown
        SelectFilterDropDownLabel   matlab.ui.control.Label
        RemoveFIlterButton          matlab.ui.control.Button
        HighBoundEditField          matlab.ui.control.NumericEditField
        HighBoundEditFieldLabel     matlab.ui.control.Label
        LowBoundEditField           matlab.ui.control.NumericEditField
        LowBoundEditFieldLabel      matlab.ui.control.Label
        ApplyFilterButton           matlab.ui.control.Button
        ChooseWaveDropDown          matlab.ui.control.DropDown
        ChooseWaveDropDownLabel     matlab.ui.control.Label
        DurationEditField           matlab.ui.control.NumericEditField
        DurationEditFieldLabel      matlab.ui.control.Label
        SamplingFreqEditField       matlab.ui.control.NumericEditField
        SamplingFreqEditFieldLabel  matlab.ui.control.Label
        PhaseEditField              matlab.ui.control.NumericEditField
        PhaseEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel     matlab.ui.control.Label
        AmplitudeEditField          matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel     matlab.ui.control.Label
        PlayButton                  matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        amplitude % Description
        frequency
        phase
        fs
        duration
        wave
        wavetype
        low_bound
        high_bound
        filter
        filter_on = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if (app.filter_on == 0)
                switch app.ChooseWaveDropDown.Value
                    case 'Sine'
                        app.wave = create_sine(app.amplitude, app.frequency, app.phase, app.fs, app.duration);
                    case 'Rectangle'
                        app.wave = create_square(app.amplitude, app.frequency, app.phase, app.fs, app.duration);
                end
            end
            
            plot(app.UIAxes, app.wave);

            Len = length(app.wave);
            F = app.fs * (-Len/2 : (Len/2 - 1))/Len ;
            h = plot_freq_domain(app.wave);
            plot(app.UIAxes_2,F,h);
            
            play_sound(app.wave, app.fs)
        end

        % Value changed function: SamplingFreqEditField
        function SamplingFreqEditFieldValueChanged(app, event)
            app.fs = app.SamplingFreqEditField.Value;
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            app.duration = app.DurationEditField.Value;
        end

        % Value changed function: PhaseEditField
        function PhaseEditFieldValueChanged(app, event)
            app.phase = app.PhaseEditField.Value;
            
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.amplitude = app.AmplitudeEditField.Value;
            
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.frequency = app.FrequencyEditField.Value;
            
        end

        % Callback function
        function GenerateWaveButtonPushed(app, event)
            app.wave = generate_sine(app.amplitude, app.frequency, app.phase, app.fs, app.duration);
        end

        % Value changed function: ChooseWaveDropDown
        function ChooseWaveDropDownValueChanged(app, event)
            app.wavetype = app.ChooseWaveDropDown.Value;
            
        end

        % Value changed function: LowBoundEditField
        function LowBoundEditFieldValueChanged(app, event)
            app.low_bound = app.LowBoundEditField.Value;
            
        end

        % Value changed function: HighBoundEditField
        function HighBoundEditFieldValueChanged(app, event)
            app.high_bound = app.HighBoundEditField.Value;
            
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)

            switch app.SelectFilterDropDown.Value
                case 'Lowpass'
                    app.wave = low_pass(app.wave,app.fs, app.low_bound);
                case 'Highpass'
                    app.wave = high_pass(app.wave,app.fs, app.high_bound);
            end
            app.filter_on =1;
        end

        % Button pushed function: RemoveFIlterButton
        function RemoveFIlterButtonPushed(app, event)
            app.filter_on = 0;
        end

        % Value changed function: SelectFilterDropDown
        function SelectFilterDropDownValueChanged(app, event)
            app.filter = app.SelectFilterDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'f(t)')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'f(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'F(w)')
            xlabel(app.UIAxes_2, 'w')
            ylabel(app.UIAxes_2, 'F(w)')
            zlabel(app.UIAxes_2, 'Z')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseEditFieldValueChanged, true);
            app.SamplingFreqEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFreqEditFieldLabel.Text = 'Sampling Freq';
            app.SamplingFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFreqEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFreqEditFieldValueChanged, true);
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.ChooseWaveDropDownLabel = uilabel(app.UIFigure);
            app.ChooseWaveDropDownLabel.Text = 'Choose Wave';
            app.ChooseWaveDropDown = uidropdown(app.UIFigure);
            app.ChooseWaveDropDown.Items = {'Sine', 'Rectangle'};
            app.ChooseWaveDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseWaveDropDownValueChanged, true);
            app.ChooseWaveDropDown.Value = 'Sine';
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.LowBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LowBoundEditFieldLabel.Text = 'Low Bound';
            app.LowBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowBoundEditField.ValueChangedFcn = createCallbackFcn(app, @LowBoundEditFieldValueChanged, true);
            app.HighBoundEditFieldLabel = uilabel(app.UIFigure);
            app.HighBoundEditFieldLabel.Text = 'High Bound';
            app.HighBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighBoundEditField.ValueChangedFcn = createCallbackFcn(app, @HighBoundEditFieldValueChanged, true);
            app.RemoveFIlterButton = uibutton(app.UIFigure, 'push');
            app.RemoveFIlterButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFIlterButtonPushed, true);
            app.RemoveFIlterButton.Text = 'Remove FIlter';
            app.SelectFilterDropDownLabel = uilabel(app.UIFigure);
            app.SelectFilterDropDownLabel.Text = 'Select Filter';
            app.SelectFilterDropDown = uidropdown(app.UIFigure);
            app.SelectFilterDropDown.Items = {'Lowpass', 'Highpass'};
            app.SelectFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFilterDropDownValueChanged, true);
            app.SelectFilterDropDown.Value = 'Lowpass';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end